classdef pid_auto_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        dSlider       matlab.ui.control.Slider
        dSliderLabel  matlab.ui.control.Label
        iSlider       matlab.ui.control.Slider
        iSliderLabel  matlab.ui.control.Label
        pSlider       matlab.ui.control.Slider
        pSliderLabel  matlab.ui.control.Label
        bias          matlab.ui.control.EditField
        psetLabel_5   matlab.ui.control.Label
        iset          matlab.ui.control.EditField
        psetLabel_4   matlab.ui.control.Label
        dset          matlab.ui.control.EditField
        psetLabel_2   matlab.ui.control.Label
        pset          matlab.ui.control.EditField
        psetLabel     matlab.ui.control.Label
        nxt           matlab.ui.control.Button
        pre           matlab.ui.control.Button
        pause         matlab.ui.control.Button
        start         matlab.ui.control.Button
        RightPanel    matlab.ui.container.Panel
        view          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        isRun = false; % 是否处于运行状态
        isPause = false; % 是否处于暂停状态
    end
    
    methods (Access = private)
        
        function func(app)
            e_1=0;      %前一时刻的偏差      
            Ee=0;       %累积偏差
            u_1=0.0;    %前一时刻的控制量
            y_1=0;       %前一时刻的输出
            %PID参数
            global kp
            global ki
            global kd
            global bias
            global pid
            global PID
            kp=PID(pid,1);    
            ki=PID(pid,2);
            kd=PID(pid,3);
            u=zeros(1,5000);
            time=zeros(1,5000);
            global p1
            global ts
            global num
            global den
            for k=1:1:5000
                time(k)=k*ts;   %时间参数
                r(k)=bias;      %给定量
                y(k)=-1*den(2)*y_1+num(2)*u_1+num(1)*u(k);
                e(k)=r(k)-y(k);   %偏差
                
                u(k)=kp*e(k)+ki*Ee+kd*(e(k)-e_1);   
                Ee=Ee+e(k);
                u_1=u(k);    
                y_1=y(k);    
                e_1=e(k);
            end
            p1=plot(app.view, time,r,time,y);xlim(app.view, [0,0.25]);ylim(app.view, [0,bias * 1.25])
            title(app.view, ['Kp=',num2str(kp),' Ki=',num2str(ki),' Kd= ',num2str(kd)]);
            app.pSlider.Value = kp;
            app.iSlider.Value = ki;
            app.dSlider.Value = kd;
            drawnow  
            pause(0.00001); 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: pset
        function psetValueChanging(app, event)
            changingValue = event.Value;
            global kp
            
            pause = true;
            kp = str2num(changingValue);
        end

        % Value changing function: iset
        function isetValueChanging(app, event)
            changingValue = event.Value;
            global ki
            
            pause = true;
            ki = str2num(changingValue);
        end

        % Value changing function: dset
        function dsetValueChanging(app, event)
            changingValue = event.Value;
            global kd
            
            pause = true;
            kd = str2num(changingValue);
        end

        % Value changing function: bias
        function biasValueChanging(app, event)
            changingValue = event.Value;
            global bias
            bias = str2num(changingValue);
        end

        % Button pushed function: start
        function startButtonPushed(app, event)
            
            global n
            global kp
            global ki
            global kd
            global bias
            global pid
            global PID
            

            global p1
            global ts
            global num
            global den
            global size
            if kp ~= 0
                if pid >= size
                    app.isRun = false;
                end
                disp("开始。")
                if app.isRun == false
                    app.isRun = true;
                    
                    ts=0.005;  %采样时间=0.005s
                    sys=tf(0.998,[0.021,1]);   %建立被控对象传递函数
                    dsys=c2d(sys,ts,'z');      %离散化
                    [num,den]=tfdata(dsys,'v');
    
                    p=0:8.5/1000:8.5;
                    i=0:1.5/1000:1.5;
                    d=11.2:-11.2/1000:0;
    
                    n=length(p);
                    p1=kp*ones(n,1);
                    i1=ki*ones(n,1);
        
                    a=zeros(n,1);
                    if kd ~= 0 && ki == 0
                        size = 2*n;
                        PID = [p',a,a;p1,i1,d'];
                    end
                    if kd == 0 && ki ~= 0
                        size = 2*n;
                        PID = [p',a,a;p1,i',a;];
                    end

                    if kd ~= 0 && ki ~= 0
                        size = 3*n;
                        PID = [p',a,a;p1,i',a;p1,i1,d'];
                    end
    
                    grid(app.view,"on");
                    
                    app.pause.Enable = true;
                    app.nxt.Enable = true;
                    app.pre.Enable = true;
                    app.pSlider.Enable = true;
                    app.iSlider.Enable = true;
                    app.dSlider.Enable = true;
                    pid = 1;
                    app.isPause = false;
                else
                    app.isPause = false;
                end
                
                while pid < size
                    if app.isPause == false
                        func(app);
                        pid = pid + 1;
                    else
                        e_1=0;      %前一时刻的偏差      
                        Ee=0;       %累积偏差
                        u_1=0.0;    %前一时刻的控制量
                        y_1=0;       %前一时刻的输出
                        %PID参数
                        u=zeros(1,5000);
                        time=zeros(1,5000);
                        for k=1:1:5000
                            time(k)=k*ts;   %时间参数
                            r(k)=bias;      %给定量
                            y(k)=-1*den(2)*y_1+num(2)*u_1+num(1)*u(k);
                            e(k)=r(k)-y(k);   %偏差
                                        
                            u(k)=kp*e(k)+ki*Ee+kd*(e(k)-e_1);   
                            Ee=Ee+e(k);
                            u_1=u(k);    
                            y_1=y(k);    
                            e_1=e(k);
                        end
                        p1=plot(app.view, time,r,time,y);xlim(app.view, [0,0.25]);ylim(app.view, [0,bias * 1.25])
                        title(app.view, ['Kp=',num2str(kp),' Ki=',num2str(ki),' Kd= ',num2str(kd)]);
                        drawnow  
                        pause(0.00001);
                    end
                end
            end
        end

        % Button pushed function: pause
        function pauseButtonPushed(app, event)
            app.isPause = true;
        end

        % Button pushed function: pre
        function preButtonPushed(app, event)
            global pid
            global n
            app.isPause = true;
            if pid > 1
                pid = pid - 1;
            end
        end

        % Button pushed function: nxt
        function nxtButtonPushed(app, event)
            global pid
            global n
            
            global size
            app.isPause = true;
            if pid < size
                pid = pid + 1;
            end
        end

        % Value changing function: pSlider
        function pSliderValueChanging(app, event)
            changingValue = event.Value;
            global kp
            
            app.isPause = true;
            kp = changingValue;
        end

        % Value changing function: iSlider
        function iSliderValueChanging(app, event)
            changingValue = event.Value;
            global ki
            
            app.isPause = true;
            ki = changingValue;
        end

        % Value changing function: dSlider
        function dSliderValueChanging(app, event)
            changingValue = event.Value;
            global kd
            
            app.isPause = true;
            kd = changingValue;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {482, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.start = uibutton(app.LeftPanel, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.start.Text = '运行';
            app.pause = uibutton(app.LeftPanel, 'push');
            app.pause.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pause.Enable = 'off';
            app.pause.Text = '暂停';
            app.pre = uibutton(app.LeftPanel, 'push');
            app.pre.ButtonPushedFcn = createCallbackFcn(app, @preButtonPushed, true);
            app.pre.Enable = 'off';
            app.pre.Text = '上一时刻';
            app.nxt = uibutton(app.LeftPanel, 'push');
            app.nxt.ButtonPushedFcn = createCallbackFcn(app, @nxtButtonPushed, true);
            app.nxt.Enable = 'off';
            app.nxt.Text = '下一时刻';
            app.psetLabel = uilabel(app.LeftPanel);
            app.psetLabel.Text = 'p set';
            app.pset = uieditfield(app.LeftPanel, 'text');
            app.pset.ValueChangingFcn = createCallbackFcn(app, @psetValueChanging, true);
            app.psetLabel_2 = uilabel(app.LeftPanel);
            app.psetLabel_2.Text = 'd set';
            app.dset = uieditfield(app.LeftPanel, 'text');
            app.dset.ValueChangingFcn = createCallbackFcn(app, @dsetValueChanging, true);
            app.psetLabel_4 = uilabel(app.LeftPanel);
            app.psetLabel_4.Text = 'i set';
            app.iset = uieditfield(app.LeftPanel, 'text');
            app.iset.ValueChangingFcn = createCallbackFcn(app, @isetValueChanging, true);
            app.psetLabel_5 = uilabel(app.LeftPanel);
            app.psetLabel_5.Text = '偏差';
            app.bias = uieditfield(app.LeftPanel, 'text');
            app.bias.ValueChangingFcn = createCallbackFcn(app, @biasValueChanging, true);
            app.pSliderLabel = uilabel(app.LeftPanel);
            app.pSliderLabel.Enable = 'off';
            app.pSliderLabel.Text = 'p';
            app.pSlider = uislider(app.LeftPanel);
            app.pSlider.Limits = [0 10];
            app.pSlider.ValueChangingFcn = createCallbackFcn(app, @pSliderValueChanging, true);
            app.pSlider.Enable = 'off';
            app.iSliderLabel = uilabel(app.LeftPanel);
            app.iSliderLabel.Enable = 'off';
            app.iSliderLabel.Text = 'i';
            app.iSlider = uislider(app.LeftPanel);
            app.iSlider.ValueChangingFcn = createCallbackFcn(app, @iSliderValueChanging, true);
            app.iSlider.Enable = 'off';
            app.dSliderLabel = uilabel(app.LeftPanel);
            app.dSliderLabel.Enable = 'off';
            app.dSliderLabel.Text = 'd';
            app.dSlider = uislider(app.LeftPanel);
            app.dSlider.Limits = [0 20];
            app.dSlider.ValueChangingFcn = createCallbackFcn(app, @dSliderValueChanging, true);
            app.dSlider.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.view = uiaxes(app.RightPanel);
            title(app.view, '调试图像')
            xlabel(app.view, 'X')
            ylabel(app.view, 'Y')
            zlabel(app.view, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pid_auto_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end